classdef project_06 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GraphsPanel                  matlab.ui.container.Panel
        ResetLabel                   matlab.ui.control.Label
        Button_7                     matlab.ui.control.Button
        FrequencyDomainGraphsLabel   matlab.ui.control.Label
        TimeDomainGraphsLabel        matlab.ui.control.Label
        Button_6                     matlab.ui.control.Button
        Button_5                     matlab.ui.control.Button
        DemodulationPanel            matlab.ui.container.Panel
        PlayLabel_2                  matlab.ui.control.Label
        Button_4                     matlab.ui.control.Button
        DemodulateButton             matlab.ui.control.Button
        AddingNoisePanel             matlab.ui.container.Panel
        PlayLabel_3                  matlab.ui.control.Label
        Button_9                     matlab.ui.control.Button
        AddnoiseButton               matlab.ui.control.Button
        NoiseEditField               matlab.ui.control.NumericEditField
        NoiseEditFieldLabel          matlab.ui.control.Label
        Knob                         matlab.ui.control.Knob
        KnobLabel                    matlab.ui.control.Label
        TypeofModulationPanel        matlab.ui.container.Panel
        PlayLabel_4                  matlab.ui.control.Label
        Button_8                     matlab.ui.control.Button
        ModulateButton               matlab.ui.control.Button
        AmplitudeofthecarrierfrequencyEditField  matlab.ui.control.NumericEditField
        AmplitudeofthecarrierfrequencyEditFieldLabel  matlab.ui.control.Label
        ModulationtypeDropDown       matlab.ui.control.DropDown
        ModulationtypeDropDownLabel  matlab.ui.control.Label
        InputtheaudiosignalPanel     matlab.ui.container.Panel
        PlayLabel                    matlab.ui.control.Label
        BrowseLabel                  matlab.ui.control.Label
        RecordLabel                  matlab.ui.control.Label
        Button_3                     matlab.ui.control.Button
        Button_2                     matlab.ui.control.Button
        orLabel                      matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        RecordingtimeEditField       matlab.ui.control.NumericEditField
        RecordingtimeEditFieldLabel  matlab.ui.control.Label
        ContextMenu                  matlab.ui.container.ContextMenu
        Menu                         matlab.ui.container.Menu
        Menu2                        matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Property % Description
             x % the input signal
            Fs = 10000 %The sampling frequency
            Fc = 4000 %The carrier frequency
            duration; %The duration of recording
            rec_brow = 0;%To check if recording or browsing
            Am_Fm = 0; %To check if Am modulation or Fm modulation
            bits = 16; %the bits of recording
            t;
            f;
            A; %amplitude of the carrier frequency
            Carrier; % the carrier channel
            Modulated_am; %am modulated signal 
            noise %% the added noise
            Demodulated_Am %demodulated am
            Demodulated;
            Demodulated1;
            noisy_signal;
            Demodulated_final;
            Modulated_final;
            fDev;
            Modulated_fm
            Demodulated_Fm;
            n; %length  of x
            x_shift; % fourier transformed and shifted
            noisy_shift;
            Modulated_shift;
            Demodulated_shift

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.duration = app.RecordingtimeEditField.Value;
            a = audiorecorder(app.Fs,app.bits,1);
            recordblocking(a,app.duration);
            app.x = getaudiodata(a);
            msgbox('audio recorded');
            app.rec_brow = 0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
               aduiofile = uigetfile('*.mp3'); %the type of file it is to read
         
               %disp(['User selected ', fullfile(path,file)]);
               [app.x,app.Fs]= audioread(aduiofile);
               %app.x = app.x(1:app.Fs*app.duration)
         
            app.rec_brow = 1;
            msgbox('audio file inputed');
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
          
                sound(app.x,app.Fs,app.bits) %play the audio
      
        end

        % Button pushed function: ModulateButton
        function ModulateButtonPushed(app, event)
            switch app.ModulationtypeDropDown.Value
                case 'AM modulation'
                    app.Am_Fm = 1;
                case 'FM modulation'
                    app.Am_Fm = 2;
            end
            app.A = app.AmplitudeofthecarrierfrequencyEditField.Value;
           % app.EditField.Value = app.Am_Fm;
            app.t = linspace(0,length(app.x)/app.Fs,length(app.x));
            app.n = length(app.x);
            app.f = (-app.n/2 : app.n/2-1).*(app.Fs/app.n);
            app.x_shift = fftshift(fft(app.x,app.n));
            app.x_shift = abs(app.x_shift);
            
            if app.Am_Fm == 1 % Am modulation 
                beginFreq = 1000 / (app.Fs/2);
                endFreq = 4000 / (app.Fs/2);
                [b,c] = butter(2, [beginFreq, endFreq], 'bandpass');
                Signal = filter(b, c, app.x);
                app.Carrier = (app.A*cos(2*pi*app.Fc*app.t)');
                app.Modulated_am = Signal.*app.Carrier;  
                app.Modulated_final = app.Modulated_am;
                %subplot(5,1,1)
                %plot(app.t,app.Modulated_am,'blue');
            
            else %Fm modulation
                app.fDev = app.Fc/2;
                app.Modulated_fm = fmmod(app.x,app.Fc,app.Fs,app.fDev);
                app.Modulated_final = app.Modulated_fm;
            end
            msgbox('modulation done')
        end

        % Value changed function: Knob
        function KnobValueChanged2(app, event)
            value = app.Knob.Value;
            app.NoiseEditField.Value = fix(value);
            l = fix(value);
            app.noise = l;
        end

        % Button pushed function: AddnoiseButton
        function AddnoiseButtonPushed2(app, event)
            %app.EditField2.Value = app.noise;
            if app.Am_Fm == 1
                app.noisy_signal = awgn(app.Modulated_am,app.noise);
            else
                app.noisy_signal = awgn(app.Modulated_fm,app.noise);
            end
            msgbox('Noise added');
          
        end

        % Button pushed function: DemodulateButton
        function DemodulateButtonPushed(app, event)
           
            if app.Am_Fm == 1 %am demodulation 
                Freq1 = 20 / (app.Fs/2);
                Freq2 = app.Fc / (app.Fs/2);
                [num,den] = butter(2,[Freq1,Freq2],'bandpass');
                app.Demodulated = filter(num,den,app.noisy_signal);
                app.Demodulated1 = 2*app.Demodulated.*app.Carrier;
                [e,f] = butter(2*2,(app.Fc/2)/app.Fs,'low');
                app.Demodulated_Am = filter(e,f,app.Demodulated1);
                app.Demodulated_final = app.Demodulated_Am;
                msgbox('Am demodulation complete')
            
            else %fm demodulation
               app.Demodulated_Fm = fmdemod(app.noisy_signal,app.Fc,app.Fs,app.fDev);
               app.Demodulated_final = app.Demodulated_Fm;
               msgbox('Fm demodulation complete');
            end
            app.Modulated_shift = fftshift(fft(app.Modulated_final,app.n));
            app.Modulated_shift = abs(app.Modulated_shift);
            app.Demodulated_shift = fftshift(fft(app.Demodulated_final,app.n));
            app.Demodulated_shift = abs(app.Demodulated_shift);
            app.noisy_shift = fftshift(fft(app.noisy_signal,app.n));
            app.noisy_shift = abs(app.noisy_shift);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            sound(app.Demodulated_final,app.Fs,app.bits);
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %% time domain for the input signal
            subplot(4,2,1);
            plot(app.t,app.x,'blue');
            xlabel('time(sec)');
            ylabel('Amplitude');
            title('Time Domain for input signal')
          %% time domain for the modulated signal 
          subplot(4,2,3)
          plot(app.t,app.Modulated_final,'blue');
          xlabel('time(sec)');
          ylabel('Amplitude');
          title('Time Domain for modulated signal')
          %% time domain for the noisy signal 
          subplot(4,2,5)
          plot(app.t,app.noisy_signal,'blue');
          xlabel('time(sec)');
          ylabel('Amplitude');
          title('Time Domain for noisy signal')
          %% time domain for the demodulated signal
          subplot(4,2,7)
          plot(app.t,app.Demodulated_final,'blue');
          xlabel('time(sec)');
          ylabel('Amplitude');
          title('Time Domain for demodulated signal')
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %% frequency domain of input signal
            subplot(4,2,2)
            plot(app.f,app.x_shift,'blue');
            xlabel('f(Hz)');
            ylabel('Amplitude');
            title('Frequency Domain for input signal');
            %% frequency domain of the modulated signal
            subplot(4,2,4)
            plot(app.f,app.Modulated_shift,'blue');
            xlabel('f(Hz)');
            ylabel('Amplitude');
            title('Frequency Domain for Modulated signal');
            %% frequency domain of the noisy  signal
            subplot(4,2,6)
            plot(app.f,app.noisy_shift,'blue');
            xlabel('f(Hz)');
            ylabel('Amplitude');
            title('Frequency Domain for the noisy signal')
            %% frequecny domain of the demodulated signal 
            subplot(4,2,8)
            plot(app.f,app.Demodulated_shift,'blue');
            xlabel('f(Hz)');
            ylabel('Amplitude');
            title('Frequency Domain for the demodulated signal')
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
             % Open 2nd instance of app
               project_06();  %The name of app
            % Delete old instance
              close(app.UIFigure)
              close all
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)

            sound(app.Modulated_final,app.Fs); %%sound the modulated signal
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            sound(app.noisy_signal,app.Fs);
        end

        % Value changed function: ModulationtypeDropDown
        function ModulationtypeDropDownValueChanged(app, event)
            value = app.ModulationtypeDropDown.Value;
            if value == 'FM modulation'
               app.AmplitudeofthecarrierfrequencyEditField.Visible = 'off';
               app.AmplitudeofthecarrierfrequencyEditFieldLabel.Visible = 'off'
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputtheaudiosignalPanel = uipanel(app.UIFigure);
            app.InputtheaudiosignalPanel.Title = 'Input the audio signal';
            app.RecordingtimeEditFieldLabel = uilabel(app.InputtheaudiosignalPanel);
            app.RecordingtimeEditFieldLabel.Text = 'Recording time';
            app.RecordingtimeEditField = uieditfield(app.InputtheaudiosignalPanel, 'numeric');
            app.Button = uibutton(app.InputtheaudiosignalPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = '01.png';
            app.Button.Text = '';
            app.orLabel = uilabel(app.InputtheaudiosignalPanel);
            app.orLabel.Text = 'or';
            app.Button_2 = uibutton(app.InputtheaudiosignalPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'browser.png';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.InputtheaudiosignalPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = 'play.png';
            app.Button_3.Text = '';
            app.RecordLabel = uilabel(app.InputtheaudiosignalPanel);
            app.RecordLabel.Text = 'Record';
            app.BrowseLabel = uilabel(app.InputtheaudiosignalPanel);
            app.BrowseLabel.Text = 'Browse';
            app.PlayLabel = uilabel(app.InputtheaudiosignalPanel);
            app.PlayLabel.Text = 'Play';
            app.TypeofModulationPanel = uipanel(app.UIFigure);
            app.TypeofModulationPanel.Title = 'Type of Modulation';
            app.ModulationtypeDropDownLabel = uilabel(app.TypeofModulationPanel);
            app.ModulationtypeDropDownLabel.Text = 'Modulation type';
            app.ModulationtypeDropDown = uidropdown(app.TypeofModulationPanel);
            app.ModulationtypeDropDown.Items = {'AM modulation', 'FM modulation'};
            app.ModulationtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ModulationtypeDropDownValueChanged, true);
            app.ModulationtypeDropDown.Value = 'AM modulation';
            app.AmplitudeofthecarrierfrequencyEditFieldLabel = uilabel(app.TypeofModulationPanel);
            app.AmplitudeofthecarrierfrequencyEditFieldLabel.Text = 'Amplitude of the carrier frequency';
            app.AmplitudeofthecarrierfrequencyEditField = uieditfield(app.TypeofModulationPanel, 'numeric');
            app.ModulateButton = uibutton(app.TypeofModulationPanel, 'push');
            app.ModulateButton.ButtonPushedFcn = createCallbackFcn(app, @ModulateButtonPushed, true);
            app.ModulateButton.Text = 'Modulate';
            app.Button_8 = uibutton(app.TypeofModulationPanel, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Icon = 'play.png';
            app.Button_8.Text = '';
            app.PlayLabel_4 = uilabel(app.TypeofModulationPanel);
            app.PlayLabel_4.Text = 'Play';
            app.AddingNoisePanel = uipanel(app.UIFigure);
            app.AddingNoisePanel.Title = 'Adding Noise';
            app.KnobLabel = uilabel(app.AddingNoisePanel);
            app.KnobLabel.Text = 'Knob';
            app.Knob = uiknob(app.AddingNoisePanel, 'continuous');
            app.Knob.ValueChangedFcn = createCallbackFcn(app, @KnobValueChanged2, true);
            app.NoiseEditFieldLabel = uilabel(app.AddingNoisePanel);
            app.NoiseEditFieldLabel.Text = 'Noise';
            app.NoiseEditField = uieditfield(app.AddingNoisePanel, 'numeric');
            app.AddnoiseButton = uibutton(app.AddingNoisePanel, 'push');
            app.AddnoiseButton.ButtonPushedFcn = createCallbackFcn(app, @AddnoiseButtonPushed2, true);
            app.AddnoiseButton.Text = 'Add noise';
            app.Button_9 = uibutton(app.AddingNoisePanel, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Icon = 'play.png';
            app.Button_9.Text = '';
            app.PlayLabel_3 = uilabel(app.AddingNoisePanel);
            app.PlayLabel_3.Text = 'Play';
            app.DemodulationPanel = uipanel(app.UIFigure);
            app.DemodulationPanel.Title = 'Demodulation';
            app.DemodulateButton = uibutton(app.DemodulationPanel, 'push');
            app.DemodulateButton.ButtonPushedFcn = createCallbackFcn(app, @DemodulateButtonPushed, true);
            app.DemodulateButton.Text = 'Demodulate';
            app.Button_4 = uibutton(app.DemodulationPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'play.png';
            app.Button_4.Text = '';
            app.PlayLabel_2 = uilabel(app.DemodulationPanel);
            app.PlayLabel_2.Text = 'Play';
            app.GraphsPanel = uipanel(app.UIFigure);
            app.GraphsPanel.Title = 'Graphs';
            app.Button_5 = uibutton(app.GraphsPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'images.png';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GraphsPanel, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Icon = 'images.png';
            app.Button_6.Text = '';
            app.TimeDomainGraphsLabel = uilabel(app.GraphsPanel);
            app.TimeDomainGraphsLabel.Text = 'Time Domain Graphs';
            app.FrequencyDomainGraphsLabel = uilabel(app.GraphsPanel);
            app.FrequencyDomainGraphsLabel.Text = 'Frequency Domain Graphs';
            app.Button_7 = uibutton(app.GraphsPanel, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Icon = 'download.png';
            app.Button_7.Text = '';
            app.ResetLabel = uilabel(app.GraphsPanel);
            app.ResetLabel.Text = 'Reset';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ContextMenu
            app.Button_7.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_06

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end